classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        NewtonsCalculatorLabel  matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        Lamp                    matlab.ui.control.Lamp
        ForceEditField          matlab.ui.control.NumericEditField
        ForceEditFieldLabel     matlab.ui.control.Label
        CalculateButton         matlab.ui.control.StateButton
        accelEditField          matlab.ui.control.NumericEditField
        accelEditFieldLabel     matlab.ui.control.Label
        massEditField           matlab.ui.control.NumericEditField
        massEditFieldLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CalculateButton
        function CalculateButtonValueChanged(app, event)
            mass = app.massEditField.Value;
            accel = app.accelEditField.Value;
            force = mass*accel;
            app.ForceEditField.Value = force;

            if force > 0 && force <=500
                app.Lamp.Color = 'g';
            elseif force > 500 && force <=1000
                app.Lamp.Color = [1.00,0.41,0.16];
            elseif force > 1000
                app.Lamp.Color = 'r';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.massEditFieldLabel = uilabel(app.Panel);
            app.massEditFieldLabel.Text = 'mass';
            app.massEditField = uieditfield(app.Panel, 'numeric');
            app.accelEditFieldLabel = uilabel(app.Panel);
            app.accelEditFieldLabel.Text = 'accel';
            app.accelEditField = uieditfield(app.Panel, 'numeric');
            app.CalculateButton = uibutton(app.Panel, 'state');
            app.CalculateButton.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonValueChanged, true);
            app.CalculateButton.Text = 'Calculate';
            app.ForceEditFieldLabel = uilabel(app.Panel);
            app.ForceEditFieldLabel.Text = 'Force';
            app.ForceEditField = uieditfield(app.Panel, 'numeric');
            app.Lamp = uilamp(app.Panel);
            app.NewtonsCalculatorLabel = uilabel(app.UIFigure);
            app.NewtonsCalculatorLabel.Text = 'Newtons Calculator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end