classdef MyoGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        SpeichernButton  matlab.ui.control.Button
        MyoAPPLabel      matlab.ui.control.Label
        Sensor8CheckBox  matlab.ui.control.CheckBox
        Sensor7CheckBox  matlab.ui.control.CheckBox
        Sensor6CheckBox  matlab.ui.control.CheckBox
        Sensor5CheckBox  matlab.ui.control.CheckBox
        Sensor4CheckBox  matlab.ui.control.CheckBox
        Sensor3CheckBox  matlab.ui.control.CheckBox
        Sensor2CheckBox  matlab.ui.control.CheckBox
        Sensor1CheckBox  matlab.ui.control.CheckBox
        StartButton      matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        m1;
        s1;
        s2;
        s3;
        s4;
        s5;
        s6;
        s7;
        s8;
        s9;
        ax; 
        tEMG;
        data_emg;
        valueCheck1;
        h;
        z;
        directory;
        recording;
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            install_myo_mex;
          
            sdk_path='C:\Users\kaste\OneDrive\Dokumente\Hochschule\Master\MasterThesis Backup\Master-Thesis\Software\Matlab - Copy\myo-sdk-win-0.9.0';
            build_myo_mex(sdk_path);
            countMyos = 1;
            mm = MyoMex(countMyos);
            app.m1 = mm.myoData(1);
            app.directory = 'C:\Users\kaste\Desktop\Master-Thesis\Software\Matlab - Copy\Raw_Data\';

            ylim(app.UIAxes,[-1.2,1.2]);
            ylabel(app.UIAxes, 'EMG [%]')
            xlabel(app.UIAxes, 'TIME [s]');
       
        end

        % Value changed function: Sensor1CheckBox
        function Sensor1CheckBoxValueChanged(app, event)
            value = app.Sensor1CheckBox.Value;
            if value == 1
                app.s1.Visible = 'on';
            else
                app.s1.Visible = 'off';
            end

        end

        % Value changed function: Sensor2CheckBox
        function Sensor2CheckBoxValueChanged(app, event)
            value = app.Sensor2CheckBox.Value;
            if value == 1
                app.s2.Visible = 'on';
            else
                app.s2.Visible = 'off';
            end
        end

        % Value changed function: Sensor3CheckBox
        function Sensor3CheckBoxValueChanged(app, event)
            value = app.Sensor3CheckBox.Value;
            if value == 1
                app.s3.Visible = 'on';
            else
                app.s3.Visible = 'off';
            end
        end

        % Value changed function: Sensor4CheckBox
        function Sensor4CheckBoxValueChanged(app, event)
            value = app.Sensor4CheckBox.Value;
            if value == 1
                app.s4.Visible = 'on';
            else
                app.s4.Visible = 'off';
            end
        end

        % Value changed function: Sensor5CheckBox
        function Sensor5CheckBoxValueChanged(app, event)
            value = app.Sensor5CheckBox.Value;
            if value == 1
                app.s5.Visible = 'on';
            else
                app.s5.Visible = 'off';
            end
        end

        % Value changed function: Sensor6CheckBox
        function Sensor6CheckBoxValueChanged(app, event)
            value = app.Sensor6CheckBox.Value;
            if value == 1
                app.s6.Visible = 'on';
            else
                app.s6.Visible = 'off';
            end
        end

        % Value changed function: Sensor7CheckBox
        function Sensor7CheckBoxValueChanged(app, event)
            value = app.Sensor7CheckBox.Value;
            if value == 1
                app.s7.Visible = 'on';
            else
                app.s7.Visible = 'off';
            end
        end

        % Value changed function: Sensor8CheckBox
        function Sensor8CheckBoxValueChanged(app, event)
            value = app.Sensor8CheckBox.Value;
            if value == 1
                app.s8.Visible = 'on';
            else
                app.s8.Visible = 'off';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.recording = 1;

            switch(app.StartButton.Text)
                case 'Start'
                    app.Sensor1CheckBox.Value=1;
                    app.Sensor2CheckBox.Value=1;
                    app.Sensor3CheckBox.Value=1;
                    app.Sensor4CheckBox.Value=1;
                    app.Sensor5CheckBox.Value=1;
                    app.Sensor6CheckBox.Value=1;
                    app.Sensor7CheckBox.Value=1;
                    app.Sensor8CheckBox.Value=1;

                    app.StartButton.Text = 'Stop';

                    
                    while (app.recording == 1)
                        pause(0.1);
                        app.m1.emg;

                        app.m1.startStreaming();
                        app.s1=animatedline(app.UIAxes,'Color','r','Visible','on');
                        app.s2=animatedline(app.UIAxes,'Color','b','Visible','on');
                        app.s3=animatedline(app.UIAxes,'Color','y','Visible','on');
                        app.s4=animatedline(app.UIAxes,'Color','g','Visible','on');
                        app.s5=animatedline(app.UIAxes,'Color','k','Visible','on');
                        app.s6=animatedline(app.UIAxes,'Color','m','Visible','on');
                        app.s7=animatedline(app.UIAxes,'Color','c','Visible','on');
                        app.s8=animatedline(app.UIAxes,'Color','r','Visible','on');

                        while(app.m1.isStreaming)
                            
                            timeEMG = app.m1.timeEMG_log;
                            if ~isempty(timeEMG)
                                indexEMG = find(timeEMG>=(timeEMG(end)));       % gets the Index by finding the positon of timeEMG changes
                                app.tEMG = timeEMG(indexEMG);                       % gets the time when there is a change
                                app.data_emg = app.m1.emg_log(indexEMG,:);              % determines the data

                                addpoints(app.s1,app.tEMG,app.data_emg(:,1));
                                addpoints(app.s2,app.tEMG,app.data_emg(:,2));
                                addpoints(app.s3,app.tEMG,app.data_emg(:,3));
                                addpoints(app.s4,app.tEMG,app.data_emg(:,4));
                                addpoints(app.s5,app.tEMG,app.data_emg(:,5));
                                addpoints(app.s6,app.tEMG,app.data_emg(:,6));
                                addpoints(app.s7,app.tEMG,app.data_emg(:,7));
                                addpoints(app.s8,app.tEMG,app.data_emg(:,8));
                                drawnow
                            end
                        end
                    end

                case 'Stop'
%                     all_data=app.m1.emg_log;
%                     T = array2table(all_data);
%                     T.Properties.VariableNames(1:8)={'Sensor1','Sensor 2','Sensor 3','Sensor 4','Sensor 5','Sensor 6','Sensor 7','Sensor 8'};
%                     if ~exist(app.directory,'dir')
%                         app.directory=pwd;
%                     end
% 
%                     fileName='EMG_raw_data.xlsx';
%                     [file,path]=uiputfile(fileName,'Save file name');
% 
%                     if file == 0
%                         app.m1.stopStreaming();
%                         app.Sensor1CheckBox.Value=0;
%                         app.Sensor2CheckBox.Value=0;
%                         app.Sensor3CheckBox.Value=0;
%                         app.Sensor4CheckBox.Value=0;
%                         app.Sensor5CheckBox.Value=0;
%                         app.Sensor6CheckBox.Value=0;
%                         app.Sensor7CheckBox.Value=0;
%                         app.Sensor8CheckBox.Value=0;
%                         cla(app.UIAxes);
%                         value=0;
%                         app.m1.clearLogs;
%                         app.StartButton.Text = 'Start';
%                         
%                         return;
%                       
%                     end
% 
%                     writetable(T,fullfile(path,file));
% 
%                     app.m1.stopStreaming();
%                     app.Sensor1CheckBox.Value=0;
%                     app.Sensor2CheckBox.Value=0;
%                     app.Sensor3CheckBox.Value=0;
%                     app.Sensor4CheckBox.Value=0;
%                     app.Sensor5CheckBox.Value=0;
%                     app.Sensor6CheckBox.Value=0;
%                     app.Sensor7CheckBox.Value=0;
%                     app.Sensor8CheckBox.Value=0;
%                     cla(app.UIAxes);
%                     app.m1.clearLogs;
%                     value=0;
                    app.m1.clearLogs;
                    
                    app.m1.stopStreaming();
                    app.recording=0;


                    app.StartButton.Text = 'Start';

            end


        end

        % Button pushed function: SpeichernButton
        function SpeichernButtonPushed(app, event)
            all_data=app.m1.emg_log;
                    T = array2table(all_data);
                    T.Properties.VariableNames(1:8)={'Sensor1','Sensor 2','Sensor 3','Sensor 4','Sensor 5','Sensor 6','Sensor 7','Sensor 8'};
                    if ~exist(app.directory,'dir')
                        app.directory=pwd;
                    end

                    fileName='EMG_raw_data.xlsx';
                    [file,path]=uiputfile(fileName,'Save file name');

                    if file == 0
                        app.recording=0;
                        app.m1.clearLogs;
                        cla(app.UIAxes);
                        
                        
                        return;
                      
                    end

                    writetable(T,fullfile(path,file));

                    app.m1.stopStreaming();
                    app.m1.clearLogs;
                    cla(app.UIAxes);
                    app.recording=0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'EMG Signals')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Sensor1CheckBox = uicheckbox(app.UIFigure);
            app.Sensor1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Sensor1CheckBoxValueChanged, true);
            app.Sensor1CheckBox.Text = 'Sensor 1';
            app.Sensor2CheckBox = uicheckbox(app.UIFigure);
            app.Sensor2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Sensor2CheckBoxValueChanged, true);
            app.Sensor2CheckBox.Text = 'Sensor 2';
            app.Sensor3CheckBox = uicheckbox(app.UIFigure);
            app.Sensor3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Sensor3CheckBoxValueChanged, true);
            app.Sensor3CheckBox.Text = 'Sensor 3';
            app.Sensor4CheckBox = uicheckbox(app.UIFigure);
            app.Sensor4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Sensor4CheckBoxValueChanged, true);
            app.Sensor4CheckBox.Text = 'Sensor 4';
            app.Sensor5CheckBox = uicheckbox(app.UIFigure);
            app.Sensor5CheckBox.ValueChangedFcn = createCallbackFcn(app, @Sensor5CheckBoxValueChanged, true);
            app.Sensor5CheckBox.Text = 'Sensor 5';
            app.Sensor6CheckBox = uicheckbox(app.UIFigure);
            app.Sensor6CheckBox.ValueChangedFcn = createCallbackFcn(app, @Sensor6CheckBoxValueChanged, true);
            app.Sensor6CheckBox.Text = 'Sensor 6';
            app.Sensor7CheckBox = uicheckbox(app.UIFigure);
            app.Sensor7CheckBox.ValueChangedFcn = createCallbackFcn(app, @Sensor7CheckBoxValueChanged, true);
            app.Sensor7CheckBox.Text = 'Sensor 7';
            app.Sensor8CheckBox = uicheckbox(app.UIFigure);
            app.Sensor8CheckBox.ValueChangedFcn = createCallbackFcn(app, @Sensor8CheckBoxValueChanged, true);
            app.Sensor8CheckBox.Text = 'Sensor 8';
            app.MyoAPPLabel = uilabel(app.UIFigure);
            app.MyoAPPLabel.Text = 'Myo APP';
            app.SpeichernButton = uibutton(app.UIFigure, 'push');
            app.SpeichernButton.ButtonPushedFcn = createCallbackFcn(app, @SpeichernButtonPushed, true);
            app.SpeichernButton.Text = 'Speichern';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyoGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end