classdef MyoDataGUI_Monitor_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        pb_stream            matlab.ui.control.Button
        pb_clear             matlab.ui.control.Button
        sl_azimuth           matlab.ui.control.Slider
        bg_frame             matlab.ui.container.ButtonGroup
        rb_fixed             matlab.ui.control.RadioButton
        rb_sensor            matlab.ui.control.RadioButton
        text2                matlab.ui.control.Label
        sl_elevation         matlab.ui.control.Slider
        st_imu_rate          matlab.ui.control.Label
        st_emg_rate          matlab.ui.control.Label
        text5                matlab.ui.control.Label
        text6                matlab.ui.control.Label
        ax_quat_strip        matlab.ui.control.UIAxes
        ax_gyro_strip        matlab.ui.control.UIAxes
        ax_accel_strip       matlab.ui.control.UIAxes
        ax_quat_orientation  matlab.ui.control.UIAxes
        ax_emg_strip         matlab.ui.control.UIAxes
        ax_image_pose        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function updateFigureCallback(app, ~, ~, handles)
            
            m = handles.myoData;
            hp = handles.hp;
            
            % get datas to plot
            timeIMU = m.timeIMU_log;
            iiIMU = find(timeIMU>=(timeIMU(end)-handles.const.STRIP_TIME));
            tIMU = timeIMU(iiIMU);
            
            q = m.quat_log(iiIMU,:);
            p = m.pose_log(iiIMU,:);
            
            if get(handles.rb_fixed,'value')
              g = m.gyro_fixed_log(iiIMU,:);
              a = m.accel_fixed_log(iiIMU,:);
            else
              g = m.gyro_log(iiIMU,:);
              a = m.accel_log(iiIMU,:);
            end
            
            set(hp.quat(1),'xdata',tIMU,'ydata',q(:,1));
            set(hp.quat(2),'xdata',tIMU,'ydata',q(:,2));
            set(hp.quat(3),'xdata',tIMU,'ydata',q(:,3));
            set(hp.quat(4),'xdata',tIMU,'ydata',q(:,4));
            
            set(hp.gyro(1),'xdata',tIMU,'ydata',g(:,1));
            set(hp.gyro(2),'xdata',tIMU,'ydata',g(:,2));
            set(hp.gyro(3),'xdata',tIMU,'ydata',g(:,3));
            set(hp.gyro(4),'xdata',tIMU,'ydata',sqrt(sum(g'.^2))');
            
            set(hp.accel(1),'xdata',tIMU,'ydata',a(:,1));
            set(hp.accel(2),'xdata',tIMU,'ydata',a(:,2));
            set(hp.accel(3),'xdata',tIMU,'ydata',a(:,3));
            set(hp.accel(4),'xdata',tIMU,'ydata',sqrt(sum(a'.^2))'-1);
            
            tpose = tIMU(m.pose_fist_log(iiIMU));
            set(hp.pose(1),'xdata',tpose,...
              'ydata',ones(1,length(tpose))*handles.const.POSE_YDATA_VALUE);
            tpose = tIMU(m.pose_wave_in_log(iiIMU));
            set(hp.pose(2),'xdata',tpose,...
              'ydata',ones(1,length(tpose))*handles.const.POSE_YDATA_VALUE);
            tpose = tIMU(m.pose_wave_out_log(iiIMU));
            set(hp.pose(3),'xdata',tpose,...
              'ydata',ones(1,length(tpose))*handles.const.POSE_YDATA_VALUE);
            tpose = tIMU(m.pose_fingers_spread_log(iiIMU));
            set(hp.pose(4),'xdata',tpose,...
              'ydata',ones(1,length(tpose))*handles.const.POSE_YDATA_VALUE);
            tpose = tIMU(m.pose_double_tap_log(iiIMU));
            set(hp.pose(5),'xdata',tpose,...
              'ydata',ones(1,length(tpose))*handles.const.POSE_YDATA_VALUE);
            
            % pose image
            if handles.image_pose_enabled
              im = [];
              switch true
                case m.pose_fist
                  im = handles.image_pose_fist;
                case m.pose_wave_in
                  im = handles.image_pose_wave_in;
                case m.pose_wave_out
                  im = handles.image_pose_wave_out;
                case m.pose_fingers_spread
                  im = handles.image_pose_fingers_spread;
                case m.pose_double_tap
                  im = handles.image_pose_double_tap;
              end
            
              % update image_pose
              if ~isempty(im)
                [M,N,~] = size(im);
                [X,Y] = meshgrid(1:M,1:N);
                A = sqrt( (X-floor(M/2)).^2 + (Y-floor(N/2)).^2 )<(floor(min([M,N])/2)-5);
                set(hp.image_pose,'cdata',im,'alphadata',A,'visible','on');
              else
                set(hp.image_pose,'visible','off');
              end
            end
            
            % update emg data
            timeEMG = m.timeEMG_log;
            if ~isempty(timeEMG)
              iiEMG = find(timeEMG>=(timeEMG(end)-handles.const.STRIP_TIME));
              tEMG = timeEMG(iiEMG);
              e = m.emg_log(iiEMG,:);
            
              for ii = 1:size(e,2)-1
                set(hp.emg(ii),'xdata',tEMG,'ydata',e(:,ii));
              end
              set(hp.emg(end),'xdata',tEMG,'ydata',sqrt(sum(e'.^2)/8)');
            end
            
            % update axes time limits
            tmp = [...
              handles.ax_quat_strip,...
              handles.ax_gyro_strip,...
              handles.ax_accel_strip,...
              handles.ax_emg_strip];
            set(tmp,'xlim',m.timeIMU - [handles.const.STRIP_TIME,0]);
            
            
            % update orientation view
            Rcurr = m.rot;
            
            R = Rcurr;
            
            H = [R,[0;0;0];0,0,0,1];
            set(hp.hx_body,'matrix',H);
            
            set(handles.st_imu_rate,'string',sprintf('%7.3f',m.rateIMU));
            set(handles.st_emg_rate,'string',sprintf('%7.3f',m.rateEMG));
            
            drawnow;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MyoDataGUI_Monitor_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to MyoDataGUI_Monitor (see VARARGIN)
            
            % constants
            handles.const.STRIP_TIME = 2;
            handles.const.UPDATE_RATE = 25; % Hz
            handles.const.UPDATE_START_DELAY = 1; % s
            handles.const.STREAMING_DATA_TIME = 0.020; % [s] (50 Hz)
            handles.const.STREAMING_FRAME_TIME = 0.040; % [s] (25 Hz)
            
            handles.const.QUAT_STRIP_YLIM = [-1,1];
            handles.const.GYRO_STRIP_YLIM = 2000*[-1,1];
            handles.const.ACCEL_STRIP_YLIM = 2*[-1,1];
            handles.const.EMG_STRIP_YLIM = [-1,1.2];
            
            handles.const.POSE_YDATA_VALUE = 1.1;
            handles.const.NUM_POSES = 5;
            
            handles.const.ROTATE_OFFSET = -135;
            
            % process inputs
            argv = varargin;
            handles.myoData = [];
            handles.closeFunc = [];
            
            assert(length(argv)>0 && isa(argv{1},'MyoData') && isscalar(argv{1}),...
              'Input (1) must be a scalar MyoData object');
            handles.myoData = argv{1};
            if length(argv)>1 && isa(argv{2},'function_handle')
              handles.closeFunc = argv{2};
            end
            
            % load image resources
            handles.image_pose_enabled = false;
            
            res_path = fullfile(fileparts(mfilename('fullpath')),'..','resources');
            if exist(res_path,'dir')
              handles.image_pose_enabled = true;
              handles.image_pose_fist             = imread(fullfile(res_path,'image_pose_fist.png'));
              handles.image_pose_wave_in          = imread(fullfile(res_path,'image_pose_wave_in.png'));
              handles.image_pose_wave_out         = imread(fullfile(res_path,'image_pose_wave_out.png'));
              handles.image_pose_fingers_spread   = imread(fullfile(res_path,'image_pose_fingers_spread.png'));
              handles.image_pose_double_tap       = imread(fullfile(res_path,'image_pose_double_tap.png'));
            end
            
            % set up quaternion orientation
            set(handles.ax_quat_orientation,...
              'nextplot','add',...
              'xlim',[-1,1],'ylim',[-1,1],'zlim',[-1,1],...
              'xtick',[],'ytick',[],'ztick',[],...
              'xcolor',get(hObject,'color'),...
              'ycolor',get(hObject,'color'),...
              'zcolor',get(hObject,'color'),...
              'dataaspectratio',[1,1,1],...
              'color',get(hObject,'color'));
            view(handles.ax_quat_orientation,...
              -handles.const.ROTATE_OFFSET,30);
            
            hp.hx_global = hgtransform('parent',handles.ax_quat_orientation);
            hp.hx_body   = hgtransform('parent',hp.hx_global);
            
            % plot body object into hx_body
            % [xb,yb,zb] = sphere(50);
            [xb,yb,zb] = cylinder(0.2,20);
            hbody = surf(-zb,yb,xb,'parent',hp.hx_body,...
              'facecolor','w',...
              'facealpha',0.8,...
              'edgecolor','k');
            
            % global coordinate axes
            plot3(handles.ax_quat_orientation,...
              [0,1],[0,0],[0,0],'r','linewidth',2,'parent',hp.hx_global);
            plot3(handles.ax_quat_orientation,...
              [0,0],[0,1],[0,0],'g','linewidth',2,'parent',hp.hx_global);
            plot3(handles.ax_quat_orientation,...
              [0,0],[0,0],[0,1],'b','linewidth',2,'parent',hp.hx_global);
            % body coordinate axes
            plot3(handles.ax_quat_orientation,...
              [0,1],[0,0],[0,0],'r','linewidth',2,'parent',hp.hx_body);
            plot3(handles.ax_quat_orientation,...
              [0,0],[0,1],[0,0],'g','linewidth',2,'parent',hp.hx_body);
            plot3(handles.ax_quat_orientation,...
              [0,0],[0,0],[0,1],'b','linewidth',2,'parent',hp.hx_body);
            % TODO plot other body representation
            
            % set up strip charts
            set(handles.ax_quat_strip,...
              'nextplot','add',...
              'xtick',[],...
              'xlim',[0,handles.const.STRIP_TIME],...
              'ylim',handles.const.QUAT_STRIP_YLIM);
            hp.quat(1) = plot(handles.ax_quat_strip,0,0,'k');
            hp.quat(2) = plot(handles.ax_quat_strip,0,0,'r');
            hp.quat(3) = plot(handles.ax_quat_strip,0,0,'g');
            hp.quat(4) = plot(handles.ax_quat_strip,0,0,'b');
            ylabel(handles.ax_quat_strip,'quat');
            legend(handles.ax_quat_strip,...
              'q_0 = s','q_1 = v_1','q_2 = v_2','q_3 = v_3',...
              'location','eastoutside');
            
            set(handles.ax_gyro_strip,...
              'nextplot','add',...
              'xtick',[],...
              'xlim',[0,handles.const.STRIP_TIME],...
              'ylim',handles.const.GYRO_STRIP_YLIM);
            hp.gyro(1) = plot(handles.ax_gyro_strip,0,0,'r');
            hp.gyro(2) = plot(handles.ax_gyro_strip,0,0,'g');
            hp.gyro(3) = plot(handles.ax_gyro_strip,0,0,'b');
            hp.gyro(4) = plot(handles.ax_gyro_strip,0,0,'k','linewidth',2);
            ylabel(handles.ax_gyro_strip,['gyro [',char(176),'/s]']);
            legend(handles.ax_gyro_strip,...
              'g_x','g_y','g_z','|g|',...
              'location','eastoutside');
            
            set(handles.ax_accel_strip,...
              'nextplot','add',...
              'xtick',[],...
              'xlim',[0,handles.const.STRIP_TIME],...
              'ylim',handles.const.ACCEL_STRIP_YLIM);
            hp.accel(1) = plot(handles.ax_accel_strip,0,0,'r');
            hp.accel(2) = plot(handles.ax_accel_strip,0,0,'g');
            hp.accel(3) = plot(handles.ax_accel_strip,0,0,'b');
            hp.accel(4) = plot(handles.ax_accel_strip,0,0,'k','linewidth',2);
            ylabel(handles.ax_accel_strip,'accel [g]');
            legend(handles.ax_accel_strip,...
              'a_x','a_y','a_z','|a|-g',...
              'location','eastoutside');
            
            set(handles.ax_emg_strip,...
              'nextplot','add',...
              'xlim',[0,handles.const.STRIP_TIME],...
              'ylim',handles.const.EMG_STRIP_YLIM);
            hp.emg = plot(handles.ax_emg_strip,0,zeros(1,8));
            hp.emg(end+1) = plot(handles.ax_emg_strip,0,0,'-k','linewidth',2);
            ylabel(handles.ax_emg_strip,'emg [%]');
            legend(handles.ax_emg_strip,...
              'emg_1','emg_2','emg_3','emg_4','emg_5','emg_6','emg_7','emg_8','|emg|',...
              'location','eastoutside');
            hp.pose = plot(handles.ax_emg_strip,...
              -1,...
              zeros(1,handles.const.NUM_POSES),...
              'o',...
              'markersize',5,'linewidth',2);
            for ii = 1:length(hp.pose)
              set(hp.pose(ii),'markerfacecolor',get(hp.pose(ii),'markeredgecolor'));
            end
            
            % adjust all strip axes to width of least wide axes
            tmp = [...
              handles.ax_quat_strip,...
              handles.ax_gyro_strip,...
              handles.ax_accel_strip,...
              handles.ax_emg_strip];
            p = cell2mat(get(tmp,'position'));
            w = min(p(:,3));
            for ii = 1:length(tmp)
              % includes static legend hacks from undocumented matlab...
              % http://undocumentedmatlab.com/blog/plot-performance
              set(tmp(ii),...
                'position',[p(ii,1:2),w,p(ii,4)],...
                'drawmode','fast',...
                'xlimmode','manual','ylimmode','manual');%,...
              %'LegendColorbarListeners',[]);
              setappdata(tmp(ii),'LegendColorbarManualSpace',1);
              setappdata(tmp(ii),'LegendColorbarReclaimSpace',1);
            end
            
            hp.image_pose = imshow(zeros(225,225,3),...
              'parent',handles.ax_image_pose);
            set(hp.image_pose,'visible','off');
            
            handles.hp = hp;
            
            handles.update_timer = timer(...
              'name','MyoMexGUI_Monitor_update_timer',...
              'busymode','drop',...
              'executionmode','fixedrate',...
              'period',1/handles.const.UPDATE_RATE,...
              'startdelay',handles.const.UPDATE_START_DELAY,...
              'timerfcn',@(src,evt)updateFigureCallback(app, src,evt,handles));
            start(handles.update_timer);
            handles.myoData.startStreaming();
            
            % Choose default command line output for MyoDataGUI_Monitor
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % force deletion if not performed manually
            stop(handles.update_timer);
            delete(handles.update_timer);
            if ~isempty(handles.closeFunc)
              handles.closeFunc();
            end
            
            % Hint: delete(hObject) closes the figure
            delete(hObject);
        end

        % Button pushed function: pb_clear
        function pb_clear_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_clear (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if ~isempty(handles.myoData)
              handles.myoData.clearLogs();
            end
            hpstrip = [...
              handles.hp.quat,...
              handles.hp.gyro,...
              handles.hp.accel,...
              handles.hp.emg',...
              handles.hp.pose'];
            for ii = 1:length(hpstrip)
              set(hpstrip(ii),'xdata',[],'ydata',[]);
            end
            set(hObject,'enable','off');
            drawnow;
        end

        % Button pushed function: pb_stream
        function pb_stream_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_stream (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            str = get(hObject,'string');
            
            switch str
              case 'Start Streaming'
                start(handles.update_timer);
                handles.myoData.startStreaming();
                set(handles.pb_clear,'enable','off');
                set(hObject,'string','Stop Streaming');
                myo_data=app.ax_emg_strip;
              case 'Stop Streaming'
                handles.myoData.stopStreaming();
                stop(handles.update_timer);
                set(handles.pb_clear,'enable','on');
                set(hObject,'string','Start Streaming');
                csvwrite('Myodata.csv',myo_data);
              otherwise
                disp('bad value');
            end
        end

        % Value changed function: sl_azimuth
        function sl_azimuth_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sl_azimuth (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            az = round(get(hObject,'value'));
            set(hObject,'value',az);
            
            el = round(get(handles.sl_elevation,'value'));
            set(handles.sl_elevation,'value',el);
            
            view(handles.ax_quat_orientation,...
              -az-handles.const.ROTATE_OFFSET,-el);
        end

        % Value changed function: sl_elevation
        function sl_elevation_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sl_elevation (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            az = round(get(handles.sl_azimuth,'value'));
            set(handles.sl_azimuth,'value',az);
            
            el = round(get(hObject,'value'));
            set(hObject,'value',el);
            
            view(handles.ax_quat_orientation,...
              -az-handles.const.ROTATE_OFFSET,-el);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'MyoDataGUI_Monitor';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.pb_stream = uibutton(app.figure1, 'push');
            app.pb_stream.ButtonPushedFcn = createCallbackFcn(app, @pb_stream_Callback, true);
            app.pb_stream.Tag = 'pb_stream';
            app.pb_stream.Text = 'Stop Streaming';
            app.pb_clear = uibutton(app.figure1, 'push');
            app.pb_clear.ButtonPushedFcn = createCallbackFcn(app, @pb_clear_Callback, true);
            app.pb_clear.Tag = 'pb_clear';
            app.pb_clear.Enable = 'off';
            app.pb_clear.Text = 'Clear Data';
            app.sl_azimuth = uislider(app.figure1);
            app.sl_azimuth.Limits = [-180 179];
            app.sl_azimuth.MajorTicks = [];
            app.sl_azimuth.ValueChangedFcn = createCallbackFcn(app, @sl_azimuth_Callback, true);
            app.sl_azimuth.MinorTicks = [];
            app.sl_azimuth.Tag = 'sl_azimuth';
            app.bg_frame = uibuttongroup(app.figure1);
            app.bg_frame.Title = 'Data Frame';
            app.bg_frame.Tag = 'bg_frame';
            app.rb_fixed = uiradiobutton(app.bg_frame);
            app.rb_fixed.Tag = 'rb_fixed';
            app.rb_fixed.Text = 'Fixed';
            app.rb_sensor = uiradiobutton(app.bg_frame);
            app.rb_sensor.Tag = 'rb_sensor';
            app.rb_sensor.Text = 'Sensor';
            app.rb_sensor.Value = true;
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Gesture';
            app.sl_elevation = uislider(app.figure1);
            app.sl_elevation.Limits = [-30 30];
            app.sl_elevation.MajorTicks = [];
            app.sl_elevation.ValueChangedFcn = createCallbackFcn(app, @sl_elevation_Callback, true);
            app.sl_elevation.MinorTicks = [];
            app.sl_elevation.Tag = 'sl_elevation';
            app.sl_elevation.Value = -30;
            app.st_imu_rate = uilabel(app.figure1);
            app.st_imu_rate.Tag = 'st_imu_rate';
            app.st_imu_rate.Text = '000.00';
            app.st_emg_rate = uilabel(app.figure1);
            app.st_emg_rate.Tag = 'st_emg_rate';
            app.st_emg_rate.Text = '000.00';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'IMU Rate [Hz}:';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'EMG Rate [Hz}:';
            app.ax_quat_strip = uiaxes(app.figure1);
            app.ax_quat_strip.Tag = 'ax_quat_strip';
            app.ax_gyro_strip = uiaxes(app.figure1);
            app.ax_gyro_strip.Tag = 'ax_gyro_strip';
            app.ax_accel_strip = uiaxes(app.figure1);
            app.ax_accel_strip.Tag = 'ax_accel_strip';
            app.ax_quat_orientation = uiaxes(app.figure1);
            app.ax_quat_orientation.Tag = 'ax_quat_orientation';
            app.ax_emg_strip = uiaxes(app.figure1);
            app.ax_emg_strip.Tag = 'ax_emg_strip';
            app.ax_image_pose = uiaxes(app.figure1);
            app.ax_image_pose.Tag = 'ax_image_pose';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyoDataGUI_Monitor_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)MyoDataGUI_Monitor_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end