classdef MyoMexGUI_Monitor_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        uipanel1       matlab.ui.container.Panel
        text2          matlab.ui.control.Label
        et_count_myos  matlab.ui.control.EditField
        pb_init        matlab.ui.control.Button
        uipanel2       matlab.ui.container.Panel
        cb_myo_data_1  matlab.ui.control.CheckBox
        cb_myo_data_2  matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        function cb_myo_data_Callback(app, hObject, eventdata, id, handles)
            
            if get(hObject,'value')
              % spawn figure
              handles.myoDataFig(id) = MyoDataGUI_Monitor(...
                handles.myoMex.myoData(id),...
                @()myoDataCloseCallback(app, hObject,id,handles));
            else
              % close figure
              close(handles.myoDataFig(id));
              handles.myoDataFig(id) = nan;
            end
            
            guidata(hObject,handles);
        end
        
        function myoDataCloseCallback(app, hObject, id, handles)
            
            if id==1
              set(handles.cb_myo_data_1,'value',0);
            elseif id==2
              set(handles.cb_myo_data_2,'value',0);
            end
            
            handles.myoDataFig(id) = nan;
            
            guidata(hObject,handles);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MyoMexGUI_Monitor_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to MyoMexGUI_Monitor (see VARARGIN)
            
            handles.myoMex = [];
            handles.myoDataFig = nan(1,2);
            
            % Choose default command line output for MyoMexGUI_Monitor
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: cb_myo_data_1
        function cb_myo_data_1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cb_myo_data_1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cb_myo_data_1
            cb_myo_data_Callback(app, hObject, eventdata, 1, handles);
        end

        % Value changed function: cb_myo_data_2
        function cb_myo_data_2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cb_myo_data_2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cb_myo_data_2
            cb_myo_data_Callback(app, hObject, eventdata, 2, handles);
        end

        % Value changed function: et_count_myos
        function et_count_myos_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to et_count_myos (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of et_count_myos as text
            %        str2double(get(hObject,'String')) returns contents of et_count_myos as a double
            str = get(hObject,'string');
            val = str2double(str);
            if isnan(val) || ~any(val==[1,2])
              set(hObject,'string','1');
              wstr = sprintf('Number of Myos must be a numeric scalar in [1,2].\n\nYou entered ''%s''.\n\nReverting to default (1).',str),...
              warndlg(wstr,'Bad value','modal');
            end
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if ~isempty(handles.myoMex)
              handles.myoMex.delete();
            end
            
            % kill myoData monitor figures
            for ii=1:length(handles.myoDataFig)
              if isnan(handles.myoDataFig(ii)), continue; end
              close(handles.myoDataFig(ii));
              delete(handles.myoDataFig(ii));
            end
            
            % Hint: delete(hObject) closes the figure
            delete(hObject);
        end

        % Button pushed function: pb_init
        function pb_init_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_init (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            countMyos = str2double(get(handles.et_count_myos,'string'));
            
            switch get(hObject,'string')
              case 'Init MyoMex'
                try
                  handles.myoMex = MyoMex(countMyos);
                catch err
                  warndlg(...
                    sprintf('MyoMex(%d) failed with message:\n\n''''\n',countMyos,err.message),...
                    'MyoMex Initialization Error','modal');
                  return;
                end
            
                % here everything initialized fine
                set(handles.cb_myo_data_1,'enable','on');
                if countMyos>1
                  set(handles.cb_myo_data_2,'enable','on');
                end
            
                set(handles.et_count_myos,'enable','off');
                set(hObject,'string','Delete MyoMex');
            
              case 'Delete MyoMex'
            
                handles.myoMex.delete();
                handles.myoMex = [];
            
                % close figures if they exist
                % kill myoData monitor figures
                for ii=1:length(handles.myoDataFig)
                  if isnan(handles.myoDataFig(ii)), continue; end
                  close(handles.myoDataFig(ii));
                  handles.myoDataFig(ii) = nan;
                end
            
                % disable checkboxes
                set(handles.cb_myo_data_1,'value',0,'enable','off');
                set(handles.cb_myo_data_2,'value',0,'enable','off');
            
                % enable count myos et
                set(handles.et_count_myos,'enable','on');
            
                set(hObject,'string','Init MyoMex');
            
            end
            
            guidata(hObject,handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'MyoMexGUI_Monitor';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'MyoMex Control';
            app.uipanel1.Tag = 'uipanel1';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Number of Myos:';
            app.et_count_myos = uieditfield(app.uipanel1, 'text');
            app.et_count_myos.ValueChangedFcn = createCallbackFcn(app, @et_count_myos_Callback, true);
            app.et_count_myos.Tag = 'et_count_myos';
            app.et_count_myos.Value = '1';
            app.pb_init = uibutton(app.uipanel1, 'push');
            app.pb_init.ButtonPushedFcn = createCallbackFcn(app, @pb_init_Callback, true);
            app.pb_init.Tag = 'pb_init';
            app.pb_init.Text = 'Init MyoMex';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'MyoData Monitors';
            app.uipanel2.Tag = 'uipanel2';
            app.cb_myo_data_1 = uicheckbox(app.uipanel2);
            app.cb_myo_data_1.ValueChangedFcn = createCallbackFcn(app, @cb_myo_data_1_Callback, true);
            app.cb_myo_data_1.Tag = 'cb_myo_data_1';
            app.cb_myo_data_1.Enable = 'off';
            app.cb_myo_data_1.Text = 'MyoMex.myoData(1)';
            app.cb_myo_data_2 = uicheckbox(app.uipanel2);
            app.cb_myo_data_2.ValueChangedFcn = createCallbackFcn(app, @cb_myo_data_2_Callback, true);
            app.cb_myo_data_2.Tag = 'cb_myo_data_2';
            app.cb_myo_data_2.Enable = 'off';
            app.cb_myo_data_2.Text = 'MyoMex.myoData(2)';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyoMexGUI_Monitor_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)MyoMexGUI_Monitor_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end